classdef buildTowerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        RunTab                     matlab.ui.container.Tab
        BuildtowerButton           matlab.ui.control.Button
        JenghisKhanLabel           matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        BuildSettingsPanel         matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        mmLabel                    matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        EditFieldLabel             matlab.ui.control.Label
        thetaEditField             matlab.ui.control.NumericEditField
        LoadSideSwitchLabel        matlab.ui.control.Label
        LoadSideSwitch             matlab.ui.control.ToggleSwitch
        SpeedKnobLabel             matlab.ui.control.Label
        SpeedKnob                  matlab.ui.control.DiscreteKnob
        ReadcurrentpositionButton  matlab.ui.control.StateButton
        ConnectionStatusLampLabel  matlab.ui.control.Label
        ConnectionStatusLamp       matlab.ui.control.Lamp
        JogModeTab                 matlab.ui.container.Tab
        Button                     matlab.ui.control.Button
        vButton                    matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        vButton_2                  matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        vButton_3                  matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        vButton_5                  matlab.ui.control.Button
        XLabel                     matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        GRIPButton                 matlab.ui.control.StateButton
        JogSpeedSliderLabel        matlab.ui.control.Label
        JogSpeedSlider             matlab.ui.control.Slider
        PassiveReadTab             matlab.ui.container.Tab
        TuningTab                  matlab.ui.container.Tab
    end


    properties (Access = public)
        serial % Description
    end

    properties (Access = private)
        connectionStatus = 0 % Is robot connected
    end

    methods (Access = private)
    
        function updateConnectionStatus(app, newStatus)
            % New status: 1 if connected, 0 if not connected
            app.connectionStatus = newStatus;
            if newStatus
                app.ConnectionStatusLamp.Color = [0 1 0]; % Turn Connection Lamp Green      
            elseif ~newStatus
                app.ConnectionStatusLamp.Color = [1 0 0]; % Turn Connection Lamp Red     
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: BuildtowerButton
        function BuildtowerButtonPushed(app, event)
            %            xsent = 0;
            %            ysent = 0;
            %            zsent = 0;
            %            thsent = 0;
            %            gripsent = 0;
            %            user = 4;
            if app.connectionStatus
                x = app.XEditField.Value;
                y = app.YEditField.Value;
                theta = app.thetaEditField.Value;
                loadSide = app.LoadSideSwitch.Value;
                speed = app.SpeedKnob; 
                multipleTrajectories(app.serial,x,y,theta,loadSide,speed);
    
                disp('Quitting')
                fclose(app.serial);
                delete(app.serial);
                clear serial
                
                app.updateConnectionStatus(0);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        clear serial
        close all
        debuggingVars();
        app.serial = selectSerial();
        app.updateConnectionStatus(1);
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.BuildtowerButton = uibutton(app.RunTab, 'push');
            app.BuildtowerButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtowerButtonPushed, true);
            app.BuildtowerButton.Text = 'Build tower';
            app.JenghisKhanLabel = uilabel(app.RunTab);
            app.JenghisKhanLabel.Text = 'Jenghis Khan';
            app.ConnectButton = uibutton(app.RunTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BuildSettingsPanel = uipanel(app.RunTab);
            app.BuildSettingsPanel.Title = 'Build Settings';
            app.XEditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.XEditFieldLabel.Text = 'X: ';
            app.XEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.XEditField.Limits = [0 300];
            app.XEditField.Value = 200;
            app.mmLabel = uilabel(app.BuildSettingsPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.BuildSettingsPanel);
            app.mmLabel_2.Text = 'mm';
            app.YEditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.YEditFieldLabel.Text = 'Y: ';
            app.YEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.YEditField.Limits = [-300 300];
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = -50;
            app.Label_3 = uilabel(app.BuildSettingsPanel);
            app.Label_3.Text = '°';
            app.EditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.EditFieldLabel.Text = 'Ɵ: ';
            app.thetaEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.thetaEditField.Limits = [-45 45];
            app.LoadSideSwitchLabel = uilabel(app.BuildSettingsPanel);
            app.LoadSideSwitchLabel.Text = 'Load Side';
            app.LoadSideSwitch = uiswitch(app.BuildSettingsPanel, 'toggle');
            app.LoadSideSwitch.Items = {'LEFT', 'RIGHT'};
            app.LoadSideSwitch.Value = 'RIGHT';
            app.SpeedKnobLabel = uilabel(app.BuildSettingsPanel);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.BuildSettingsPanel, 'discrete');
            app.SpeedKnob.Items = {'Accurate', 'Balanced', 'Full Jenghis'};
            app.SpeedKnob.Value = 'Accurate';
            app.ReadcurrentpositionButton = uibutton(app.BuildSettingsPanel, 'state');
            app.ReadcurrentpositionButton.Text = {'Read '; 'current '; 'position'};
            app.ConnectionStatusLampLabel = uilabel(app.RunTab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.RunTab);
            app.JogModeTab = uitab(app.TabGroup);
            app.JogModeTab.Title = 'Jog Mode';
            app.Button = uibutton(app.JogModeTab, 'push');
            app.Button.Text = '^';
            app.vButton = uibutton(app.JogModeTab, 'push');
            app.vButton.Text = 'v';
            app.Button_2 = uibutton(app.JogModeTab, 'push');
            app.Button_2.Text = '^';
            app.vButton_2 = uibutton(app.JogModeTab, 'push');
            app.vButton_2.Text = 'v';
            app.Button_3 = uibutton(app.JogModeTab, 'push');
            app.Button_3.Text = '^';
            app.vButton_3 = uibutton(app.JogModeTab, 'push');
            app.vButton_3.Text = 'v';
            app.Button_5 = uibutton(app.JogModeTab, 'push');
            app.Button_5.Text = '^';
            app.vButton_5 = uibutton(app.JogModeTab, 'push');
            app.vButton_5.Text = 'v';
            app.XLabel = uilabel(app.JogModeTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.JogModeTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.JogModeTab);
            app.ZLabel.Text = 'Z';
            app.Label = uilabel(app.JogModeTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.JogModeTab);
            app.Label_2.Text = 'Ɵ';
            app.GRIPButton = uibutton(app.JogModeTab, 'state');
            app.GRIPButton.Text = 'GRIP';
            app.JogSpeedSliderLabel = uilabel(app.JogModeTab);
            app.JogSpeedSliderLabel.Text = 'Jog Speed';
            app.JogSpeedSlider = uislider(app.JogModeTab);
            app.JogSpeedSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.PassiveReadTab = uitab(app.TabGroup);
            app.PassiveReadTab.Title = 'Passive Read';
            app.TuningTab = uitab(app.TabGroup);
            app.TuningTab.Title = 'Tuning';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buildTowerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end